classdef ZeroDetection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        v10Label                      matlab.ui.control.Label
        IMUPanel                      matlab.ui.container.Panel
        sLabel                        matlab.ui.control.Label
        sStartEditField               matlab.ui.control.NumericEditField
        Label                         matlab.ui.control.Label
        EditField                     matlab.ui.control.NumericEditField
        Button_4                      matlab.ui.control.Button
        sLabel_2                      matlab.ui.control.Label
        sEndEditField_2               matlab.ui.control.NumericEditField
        gmeanEditFieldLabel           matlab.ui.control.Label
        gmeanEditField                matlab.ui.control.NumericEditField
        zRMSLabel                     matlab.ui.control.Label
        grmsEditField                 matlab.ui.control.NumericEditField
        gLabel                        matlab.ui.control.Label
        gEditField                    matlab.ui.control.NumericEditField
        zRMSLabel_2                   matlab.ui.control.Label
        grmsThresholdEditField        matlab.ui.control.NumericEditField
        xRMSLabel                     matlab.ui.control.Label
        xrmsThresholdEditField        matlab.ui.control.NumericEditField
        xmeanLabel                    matlab.ui.control.Label
        xmeanEditField                matlab.ui.control.NumericEditField
        xEditFieldLabel               matlab.ui.control.Label
        xmeanThresholdEditField       matlab.ui.control.NumericEditField
        xrmsLabel_2                   matlab.ui.control.Label
        xrmsEditField                 matlab.ui.control.NumericEditField
        Panel                         matlab.ui.container.Panel
        Button                        matlab.ui.control.Button
        EditField_2                   matlab.ui.control.EditField
        CheckBox                      matlab.ui.control.CheckBox
        Label_2                       matlab.ui.control.Label
        EditField_3                   matlab.ui.control.NumericEditField
        MPU_AL1R2ADI_BL3R4Label       matlab.ui.control.Label
        Button_DataSpecifi            matlab.ui.control.Button
        ButtonGroup                   matlab.ui.container.ButtonGroup
        GyroButton                    matlab.ui.control.RadioButton
        AccelButton                   matlab.ui.control.RadioButton
        GyAcButton                    matlab.ui.control.RadioButton
        Button_2                      matlab.ui.control.Button
        EditField_StateCorrectLength  matlab.ui.control.NumericEditField
        Button_StateCorrect           matlab.ui.control.Button
        Panel_2                       matlab.ui.container.Panel
        GyroCheckBox                  matlab.ui.control.CheckBox
        AccelCheckBox                 matlab.ui.control.CheckBox
        Gyro_And_AccelCheckBox        matlab.ui.control.CheckBox
        Button_3                      matlab.ui.control.Button
    end

    
    properties (Access = public)
        imuDataPath    string
        imuDataName    string        
        IMUData        double
        IMUData_isOK    double
        
        % 特性参数相关
        Fs                      double  %采样频率
        timeUsedStart           double  %用于提取器件特性参数的起始时间  单位s
        timeUsedEnd             double

        meanAccel_threshold     double  %加计 g 测量幅值 判断阈值的倍数
        meanAccel               double  %加计 测量g 的均值
        rmsAccel_threshold      double  %加计 测量g 的均方差 判断阈值的倍数
        rmsAccel                double  %加计 测量g 的均方差        
        
        meanXGyro_threshold     double  %加计 x轴陀螺 判断阈值的倍数
        meanXGyro               double  %加计 x轴陀螺静止 的均值        
        rmsXGyro_threshold      double  %x轴陀螺输出方差判断阈值的倍数 
        rmsXGyro                double  %X轴陀螺输出的均方差
        
        sizeWindows             double  %步态检测 窗口长度        
        paraSet_isOK            double
        
        RmsGyro                 double  % x轴陀螺的方差记录
        meanGyro                double  % x轴陀螺的均值记录
        RmsAccel                double  % 加计的方差记录
        MeanAccel               double  % 加计的均值记录
        
        % 步态检测结果
        FootState_Gyro          double
        FootState_Gyro_isOK     double   
        FootState_Accel          double
        FootState_Accel_isOK     double     
        FootState_GyroAccel          double
        FootState_GyroAccel_isOK     double 
        
        %步态检测结果修正参数
        StateCorrectLength          double
    end
    
    methods (Access = public)

%==========================================================================        
        % 步态检测 陀螺
        function results = ZeroDetection_Gyro(app)
            L = length(app.IMUData);
            app.FootState_Gyro = zeros(L,2);     % 步态状态，第一列时间，第二列状态：0运动，1静止
            app.FootState_Gyro(:,1) = app.IMUData(:,1);                     

            app.RmsGyro = zeros(L,2);
            app.meanGyro = zeros(L,1);
            numSize = app.sizeWindows;
            for i = 1:L
                %初始设定为静态
                if i<numSize
                    app.FootState_Gyro(i,2) = 1;
                    continue;
                end
                
                %判断当前时刻之前累积的numSize个 陀螺 信息数据是否为静态
                tmpRmsGyro = sqrt(var(app.IMUData(i-numSize+1:i,5)));
                app.meanGyro(i,1) = abs(mean(app.IMUData(i-numSize+1:i,5)));
                if ( (tmpRmsGyro <= (app.rmsXGyro*app.rmsXGyro_threshold)) && ...
                       (abs(app.meanGyro(i,1)-app.meanXGyro)/app.meanXGyro <= app.meanXGyro_threshold) )
                    app.FootState_Gyro(i,2) = 1;                    
                end                  
                app.RmsGyro(i,1) = i;
                app.RmsGyro(i,2) = tmpRmsGyro;
            end
            
            app.FootState_Gyro_isOK = 1;
            app.GyroCheckBox.Enable = 1;      
            results = 1;    
            
            State_Gy = app.FootState_Gyro;
            save(fullfile(app.imuDataPath,app.imuDataName),'State_Gy','-append');

        end
        
%==========================================================================        
        % 步态检测 利用加速度计的数据检测
        %   这里按照时间间隔来判断，而不是每一个点都判断，周期为KF滤波的整数倍，便于应用
        function results = ZeroDetection_Accel(app)
            L = length(app.IMUData);            
            app.FootState_Accel = zeros(L,2);     % 步态状态，第一列时间，第二列状态：0运动，1静止
            app.FootState_Accel(:,1) = app.IMUData(:,1);
            amplAccel = sqrt(app.IMUData(:,2).^2+app.IMUData(:,3).^2+app.IMUData(:,4).^2);
            numSize = app.sizeWindows;
            
            app.RmsAccel = zeros(L,2);
            app.MeanAccel = zeros(L,1);
            for i = 1:L
                %初始设定为静态
                if i<numSize
                    app.FootState_Accel(i,2) = 1;
                    continue;
                end
                
                %判断当前时刻之前累积的numSize个 加速度计 信息数据是否为静态
                tmpRmsAccel = sqrt(var(amplAccel(i-numSize+1:i,1)));
                app.MeanAccel(i,1) = mean(amplAccel(i-numSize+1:i,1));
                if ((tmpRmsAccel <= (app.rmsAccel*app.rmsAccel_threshold)) && ...
                        (abs(app.MeanAccel(i,1)-app.meanAccel)/app.meanAccel <= app.meanAccel_threshold) )
                    app.FootState_Accel(i,2) = 1;                    
                end        
                app.RmsAccel(i,1) = i;
                app.RmsAccel(i,2) = tmpRmsAccel;                
            end            
            
            app.FootState_Accel_isOK = 1;
            app.AccelCheckBox.Enable = 1;
            results = 1;
            
            State_Ac = app.FootState_Accel;
            save(fullfile(app.imuDataPath,app.imuDataName),'State_Ac','-append');
            
            % 写论文需要 把结果保存下来，绘制图形用
%             IMUData = app.IMUData;
%             FootState_Accel = app.FootState_Accel;
%             rmsAccel = app.rmsAccel;   rmsAccel_threshold = app.rmsAccel_threshold;
%             save('D:\FootState_Accel.mat','IMUData','RmsAccel',"FootState_Accel",'amplAccel','rmsAccel','rmsAccel_threshold'); 

        end

%==========================================================================                
        % 利用陀螺 和 加计 的共同判断
        function results = ZeroDetection_GyroAccel(app)
            L = length(app.IMUData);
            app.FootState_GyroAccel = zeros(L,2);     % 步态状态，第一列时间，第二列状态：0运动，1静止
            app.FootState_GyroAccel(:,1) = app.IMUData(:,1);    
            numSize = app.sizeWindows;           
            
            % x轴陀螺幅值            
            app.meanGyro = zeros(L,1);
            % 加速度幅值
            amplAccel = sqrt(app.IMUData(:,2).^2+app.IMUData(:,3).^2+app.IMUData(:,4).^2);
            app.MeanAccel = zeros(L,1);
            for i = 1:L
                %初始设定为静态
                if i<numSize
                    app.FootState_GyroAccel(i,2) = 1;
                    continue;
                end
                
                %当前时刻之前累积的numSize个 陀螺 信息数据的均值
                app.meanGyro(i,1) = abs(mean(app.IMUData(i-numSize+1:i,5)));
                %当前时刻之前累积的numSize个 加速度计 信息数据的均值
                app.MeanAccel(i,1) = mean(amplAccel(i-numSize+1:i,1));
                
                if ( (abs(app.meanGyro(i,1)-app.meanXGyro)/app.meanXGyro <= app.meanXGyro_threshold) && ...
                       (abs(app.MeanAccel(i,1)-app.meanAccel)/app.meanAccel <= app.meanAccel_threshold) )
                    app.FootState_GyroAccel(i,2) = 1;                    
                end                  
            end            

            app.FootState_GyroAccel_isOK = 1;
            app.Gyro_And_AccelCheckBox.Enable = 1;  
            results = 1;                

            State_GyAc = app.FootState_GyroAccel;
            save(fullfile(app.imuDataPath,app.imuDataName),'State_GyAc','-append');
            
        end
        
        function result = StateCorrect(app)     
            
            if app.GyroButton.Value == 1
                state = app.FootState_Gyro;
            end
            if app.AccelButton.Value == 1
                state = app.FootState_Accel;
            end           
            if app.GyAcButton.Value == 1
                state = app.FootState_GyroAccel;                
            end
            n = length(state);
            CorrectWide = app.StateCorrectLength;    

            % 对处理结果进行纠偏 纠正跳点
            TStartSerial = 2;
            StateNum = 1;
            i = 1;
            while i < n
                i = i+1;
                if state(i,2) ~= state(i-1,2)
                    if StateNum >= CorrectWide
                        %正常变换
                        TStartSerial = i;
                        StateNum = 1;
                    else
                        if TStartSerial <= i-1
                            %有跳点
                            state(TStartSerial:i-1,2) = state(TStartSerial-1,2);
                            StateNum = StateNum+CorrectWide;
                        end
                    end
                else
                    StateNum = StateNum + 1;
                end
            end
            
            if app.GyroButton.Value == 1
                app.FootState_Gyro = state;
            end
            if app.AccelButton.Value == 1
                app.FootState_Accel = state;
            end           
            if app.GyAcButton.Value == 1
                app.FootState_GyroAccel = state;                
            end
            
            result = 1;           
        end        
        
        
        % 进行步态检测结果绘制
        function results = PlotFootState(app)
            
            if app.GyroButton.Value == 1
                % 把结果绘制出来
                %   幅值判断情况
                L = length(app.IMUData);
                figure;
                %  x轴陀螺原始输出
                plot((1:L),app.IMUData(1:L,5),'Color','k','DisplayName','xGyro');  
                %  x轴陀螺 方差统计
                hold on; plot(app.RmsGyro(:,1),app.RmsGyro(:,2),'Color','k','LineWidth',2,'DisplayName','xGyro 方差'); 
                %  x轴陀螺 方差阈值
                hold on; plot((1:L),ones(L,1).*(app.rmsXGyro*app.rmsXGyro_threshold),'Color','k','LineStyle','-.','DisplayName','xGyro 方差阈值');  
                             
                %  x轴陀螺幅值
                hold on; plot((1:L),app.meanGyro,'Color','m','DisplayName','xGyro 幅值');
                %  x轴陀螺幅值 阈值
                hold on; plot((1:L),ones(L,1).*(app.meanXGyro*(1+app.meanXGyro_threshold)),'Color','m','LineStyle','-.','DisplayName','XGyro 幅值阈值+'); 
                hold on; plot((1:L),ones(L,1).*(app.meanXGyro*(1-app.meanXGyro_threshold)),'Color','m','LineStyle','-.','DisplayName','XGyro 幅值阈值-');
                
                %判断结果
                hold on; plot((1:L),app.FootState_Gyro(:,2).*1.5,'Color','r','DisplayName','State');  
                title('x轴陀螺 方差+幅值  判断');legend;     
                results = 1;
                return;
            end
            if app.AccelButton.Value == 1
                % 把结果绘制出来
                %   加速度的方差判断情况
                L = length(app.IMUData);
                figure;
                %  Z轴加速度计原始输出
                plot((1:L),app.IMUData(1:L,4)-1,'Color','k','DisplayName','zAccel');  
                %  Z轴加速度计 方差统计
                hold on; plot(app.RmsAccel(:,1),app.RmsAccel(:,2),'Color','k','LineWidth',2,'DisplayName','zAccel 方差'); 
                %  Z轴加速度计 方差阈值
                hold on; plot((1:L),ones(L,1).*(app.rmsAccel*app.rmsAccel_threshold),'Color','k','LineStyle','-.','DisplayName','zAccel 方差阈值');  
                
                %  加速度计幅值
                hold on; plot((1:L),app.MeanAccel,'Color','m','DisplayName','Accel 幅值');
                %  加速度计幅值 阈值
                hold on; plot((1:L),ones(L,1).*(app.meanAccel*(1+app.meanAccel_threshold)),'Color','m','LineStyle','-.','DisplayName','Accel 幅值阈值+'); 
                hold on; plot((1:L),ones(L,1).*(app.meanAccel*(1-app.meanAccel_threshold)),'Color','m','LineStyle','-.','DisplayName','Accel 幅值阈值-');
                
                %判断结果
                hold on; plot((1:L),app.FootState_Accel(:,2).*1.5,'Color','r','DisplayName','state');  
                title('加计幅值+方差  判断');legend;  
                results = 1;
                return;
            end           
            if app.GyAcButton.Value == 1
                L = length(app.IMUData);
                % 绘制结果
                figure;                        
                %  x轴陀螺幅值
                plot((1:L),app.meanGyro,'Color','k','DisplayName','xGyro 幅值');
                %  x轴陀螺幅值 阈值
                hold on; plot((1:L),ones(L,1).*(app.meanXGyro*(1+app.meanXGyro_threshold)),'Color','k','DisplayName','XGyro 幅值阈值+'); 
                hold on; plot((1:L),ones(L,1).*(app.meanXGyro*(1-app.meanXGyro_threshold)),'Color','k','DisplayName','XGyro 幅值阈值-');
                
                %  加速度计幅值
                hold on; plot((1:L),app.MeanAccel,'Color','m','DisplayName','Accel 幅值');
                %  加速度计幅值 阈值
                hold on; plot((1:L),ones(L,1).*(app.meanAccel*(1+app.meanAccel_threshold)),'Color','m','LineStyle','-.','DisplayName','Accel 幅值阈值+'); 
                hold on; plot((1:L),ones(L,1).*(app.meanAccel*(1-app.meanAccel_threshold)),'Color','m','LineStyle','-.','DisplayName','Accel 幅值阈值-');            
                
                %判断结果
                hold on; plot((1:L),app.FootState_GyroAccel(:,2).*1.5,'Color','r','DisplayName','state');  
                title('x轴陀螺幅值+ 加速度幅值  判断');legend;    
                results = 1;
                return;                
            end            
            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.timeUsedStart = app.sStartEditField.Value;
            app.timeUsedEnd = app.sEndEditField_2.Value;
            app.meanXGyro = app.xmeanEditField.Value;
            app.meanXGyro_threshold = app.xmeanThresholdEditField.Value;
            app.rmsXGyro_threshold = app.xrmsThresholdEditField.Value;
            app.rmsXGyro = app.xrmsEditField.Value;
            app.meanAccel_threshold = app.gEditField.Value;
            app.meanAccel = app.gmeanEditField.Value;
            app.rmsAccel_threshold = app.grmsThresholdEditField.Value;
            app.rmsAccel = app.grmsEditField.Value;            
            app.sizeWindows = app.EditField.Value;
            app.Fs = 200;
            app.IMUData_isOK = 0;
            app.paraSet_isOK = 0;
            app.FootState_Gyro_isOK = 0;
            app.StateCorrectLength = app.EditField_StateCorrectLength.Value;
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            [IMU_FileName,IMU_FilePath] = uigetfile('*.mat');
            if isequal(IMU_FileName,0)
               msgbox('警告：未选中IMU数据！！！');
               app.IMUData_isOK = 0;
            else               
               app.imuDataPath = IMU_FilePath;
               app.imuDataName = IMU_FileName;
               app.EditField_2.Value = fullfile(IMU_FilePath,IMU_FileName);
               load(fullfile(IMU_FilePath,IMU_FileName));
               if exist('IMU') ~= 1
                   msgbox('警告：IMU数据为空！');
                   app.IMUData_isOK = 0;
               else
                   if app.CheckBox.Value == 0
                      app.IMUData = IMU;         
                      app.IMUData_isOK = 1;
                   else
                      % 进行非正交校正 
                      switch app.EditField_3.Value
                          % ADI UB R                          
                          case 4
                              app.IMUData = IMU;   
                              M_ADI_UB_R = [0.999844387	0.01471722 	0.002556173
                                            -0.01469017	0.99978481 	0.00053635
                                            -0.01245593	-0.00133620 	1.000481267];
                              L = length(app.IMUData);
                              for i=1:L
                                  tmp = M_ADI_UB_R*[app.IMUData(i,2);app.IMUData(i,3);app.IMUData(i,4)];
                                  app.IMUData(i,2) = tmp(1,1);
                                  app.IMUData(i,3) = tmp(2,1);
                                  app.IMUData(i,4) = tmp(3,1);
                              end
                              app.IMUData_isOK = 1;
                          otherwise
                              msgbox('器件类型选择错误！');
                              app.IMUData_isOK = 0;
                              return;
                      end
                   end
               end
            end                 
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            if isempty(app.IMUData) == 1
                msgbox('警告：IMU数据为空！');
                return;
            end            
            
            %计算 所需的时间长度
            L_IMU = length(app.IMUData);
            L = (app.timeUsedEnd - app.timeUsedStart) * app.Fs;
            if (L <= 0) || (L > L_IMU)
                msgbox('参数提取时间设置错误！');
                return;
            end
            tpNumStart = app.timeUsedStart*app.Fs;
            if tpNumStart == 0
                tpNumStart = 1;
            end
            tpNumEnd = app.timeUsedEnd*app.Fs;
            
            L = tpNumEnd - tpNumStart + 1;
            
            %提取 陀螺 和 加计 原始数据
            xGyroData = app.IMUData(tpNumStart:tpNumEnd,5);
            xAccelData = app.IMUData(tpNumStart:tpNumEnd,2);
            yAccelData = app.IMUData(tpNumStart:tpNumEnd,3);
            zAccelData = app.IMUData(tpNumStart:tpNumEnd,4);
            
            %计算相应的特征参数
            % 加计幅值 均值 计算
            amplAccel = sqrt(xAccelData.^2+yAccelData.^2+zAccelData.^2);
            temp = mean(amplAccel);
            app.meanAccel = temp;
            app.gmeanEditField.Value = temp;
            
            % 加计幅值方差计算
            temp = sqrt(var(amplAccel));
            app.rmsAccel = temp;
            app.grmsEditField.Value = temp;            
            
            % x陀螺幅值静止均值 计算
            amplGyro = abs(mean(xGyroData));
            app.meanXGyro = amplGyro;
            app.xmeanEditField.Value = amplGyro;
            
            % x陀螺输出均方差
            temp = sqrt(var(xGyroData));
            app.xrmsEditField.Value = temp;
            app.rmsXGyro = temp;
            
            app.paraSet_isOK = 1;
            msgbox('参数提取完成！');
        end

        % Value changed function: sStartEditField
        function sStartEditFieldValueChanged(app, event)
            app.timeUsedStart = app.sStartEditField.Value;             
        end

        % Value changed function: xrmsThresholdEditField
        function xrmsThresholdEditFieldValueChanged(app, event)
            app.rmsXGyro_threshold = app.xrmsThresholdEditField.Value;            
        end

        % Value changed function: gEditField
        function gEditFieldValueChanged(app, event)
            app.meanAccel_threshold = app.gEditField.Value;            
        end

        % Value changed function: grmsThresholdEditField
        function grmsThresholdEditFieldValueChanged(app, event)
            app.rmsAccel_threshold = app.grmsThresholdEditField.Value;            
        end

        % Value changed function: xrmsEditField
        function xrmsEditFieldValueChanged(app, event)
            app.rmsXGyro = app.xrmsEditField.Value;            
        end

        % Value changed function: gmeanEditField
        function gmeanEditFieldValueChanged(app, event)
            app.meanAccel = app.gmeanEditField.Value;            
        end

        % Value changed function: grmsEditField
        function grmsEditFieldValueChanged(app, event)
            app.rmsAccel = app.grmsEditField.Value;            
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            app.sizeWindows = app.EditField.Value;            
        end

        % Value changed function: sEndEditField_2
        function sEndEditField_2ValueChanged(app, event)
            app.timeUsedEnd = app.sEndEditField_2.Value;            
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            if (app.IMUData_isOK == 0) || (app.paraSet_isOK == 0)
                msgbox('前面参数设置有问题！')
                return;
            end
            
            if app.GyroButton.Value == 1
                results = ZeroDetection_Gyro(app);
                results = PlotFootState(app);
                return;
            end
            if app.AccelButton.Value == 1
                results = ZeroDetection_Accel(app);
                results = PlotFootState(app);
                return;
            end           
            if app.GyAcButton.Value == 1
                results = ZeroDetection_GyroAccel(app);
                results = PlotFootState(app);
                return;                
            end
            
            
            msgbox('检测算法选择错误！');
            
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            % 绘制零速检测结果数据
            tmp = sqrt(app.IMUData(:,2).^2+app.IMUData(:,3).^2+app.IMUData(:,4).^2) - 1;
            figure;
            plot(app.IMUData(:,5).*50,'Color','g','DisplayName','xGyro*50');
            hold on;
            plot(tmp.*30,'Color','m','DisplayName','(g-1)*30');
            if app.GyroCheckBox.Value == 1
                hold on;
                plot(app.FootState_Gyro(:,2).*30,'Color','k','DisplayName','xGyro state');
            end
            if app.AccelCheckBox.Value == 1
                hold on;
                plot(app.FootState_Accel(:,2).*30,'Color','r','DisplayName','Accel state');                
            end
            if app.Gyro_And_AccelCheckBox.Value == 1
                hold on;
                plot(app.FootState_GyroAccel(:,2).*30,'Color','b','DisplayName','xGyroAccel state');                
            end            
            
            legend;
        end

        % Value changed function: xmeanThresholdEditField
        function xmeanThresholdEditFieldValueChanged(app, event)
            app.meanXGyro_threshold = app.xmeanThresholdEditField.Value;            
        end

        % Value changed function: EditField_StateCorrectLength
        function EditField_StateCorrectLengthValueChanged(app, event)
            value = app.EditField_StateCorrectLength.Value;
            app.StateCorrectLength = value;
        end

        % Button pushed function: Button_StateCorrect
        function Button_StateCorrectPushed(app, event)
            % 对步态检测数据进行修正
            results = StateCorrect(app);
            
            % 绘制修正后的检测结果
            results = PlotFootState(app);     
        end

        % Button pushed function: Button_DataSpecifi
        function Button_DataSpecifiPushed(app, event)
            msgbox('输入数据说明:(1)数据名IMU;(2)数据1列为时间，234加计xyz，567陀螺xyz。');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.v10Label = uilabel(app.UIFigure);
            app.v10Label.Text = {'单兵导航_步态识别_v1.0'; ''};
            app.IMUPanel = uipanel(app.UIFigure);
            app.IMUPanel.Title = '2.IMU步态识别参数设定';
            app.sLabel = uilabel(app.IMUPanel);
            app.sLabel.Text = '参数提取开始(s)';
            app.sStartEditField = uieditfield(app.IMUPanel, 'numeric');
            app.sStartEditField.ValueChangedFcn = createCallbackFcn(app, @sStartEditFieldValueChanged, true);
            app.Label = uilabel(app.IMUPanel);
            app.Label.Text = '识别时间窗长度';
            app.EditField = uieditfield(app.IMUPanel, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Value = 8;
            app.Button_4 = uibutton(app.IMUPanel, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '参数提取';
            app.sLabel_2 = uilabel(app.IMUPanel);
            app.sLabel_2.Text = '参数提取结束(s)';
            app.sEndEditField_2 = uieditfield(app.IMUPanel, 'numeric');
            app.sEndEditField_2.ValueChangedFcn = createCallbackFcn(app, @sEndEditField_2ValueChanged, true);
            app.sEndEditField_2.Value = 5;
            app.gmeanEditFieldLabel = uilabel(app.IMUPanel);
            app.gmeanEditFieldLabel.Text = {'加计g幅值mean'; ''};
            app.gmeanEditField = uieditfield(app.IMUPanel, 'numeric');
            app.gmeanEditField.ValueChangedFcn = createCallbackFcn(app, @gmeanEditFieldValueChanged, true);
            app.zRMSLabel = uilabel(app.IMUPanel);
            app.zRMSLabel.Text = {'加计z轴RMS'; ''};
            app.grmsEditField = uieditfield(app.IMUPanel, 'numeric');
            app.grmsEditField.ValueChangedFcn = createCallbackFcn(app, @grmsEditFieldValueChanged, true);
            app.gLabel = uilabel(app.IMUPanel);
            app.gLabel.Text = '加计g幅值检测阈值';
            app.gEditField = uieditfield(app.IMUPanel, 'numeric');
            app.gEditField.ValueChangedFcn = createCallbackFcn(app, @gEditFieldValueChanged, true);
            app.gEditField.Value = 0.03;
            app.zRMSLabel_2 = uilabel(app.IMUPanel);
            app.zRMSLabel_2.Text = '加计zRMS检测阈值';
            app.grmsThresholdEditField = uieditfield(app.IMUPanel, 'numeric');
            app.grmsThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @grmsThresholdEditFieldValueChanged, true);
            app.grmsThresholdEditField.Value = 8;
            app.xRMSLabel = uilabel(app.IMUPanel);
            app.xRMSLabel.Text = '陀螺xRMS检测阈值';
            app.xrmsThresholdEditField = uieditfield(app.IMUPanel, 'numeric');
            app.xrmsThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @xrmsThresholdEditFieldValueChanged, true);
            app.xrmsThresholdEditField.Value = 3;
            app.xmeanLabel = uilabel(app.IMUPanel);
            app.xmeanLabel.Text = '陀螺x轴幅值mean';
            app.xmeanEditField = uieditfield(app.IMUPanel, 'numeric');
            app.xEditFieldLabel = uilabel(app.IMUPanel);
            app.xEditFieldLabel.Text = '陀螺x幅值检测阈值';
            app.xmeanThresholdEditField = uieditfield(app.IMUPanel, 'numeric');
            app.xmeanThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @xmeanThresholdEditFieldValueChanged, true);
            app.xmeanThresholdEditField.Value = 10;
            app.xrmsLabel_2 = uilabel(app.IMUPanel);
            app.xrmsLabel_2.Text = '陀螺x轴幅值rms';
            app.xrmsEditField = uieditfield(app.IMUPanel, 'numeric');
            app.xrmsEditField.ValueChangedFcn = createCallbackFcn(app, @xrmsEditFieldValueChanged, true);
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '1.读取数据';
            app.Button = uibutton(app.Panel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '打开';
            app.EditField_2 = uieditfield(app.Panel, 'text');
            app.CheckBox = uicheckbox(app.Panel);
            app.CheckBox.Text = '非正交校正';
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = '器件';
            app.EditField_3 = uieditfield(app.Panel, 'numeric');
            app.EditField_3.Value = 4;
            app.MPU_AL1R2ADI_BL3R4Label = uilabel(app.Panel);
            app.MPU_AL1R2ADI_BL3R4Label.Text = {'MPU_A：L 1  R 2'; 'ADI_B：  L 3 R 4'};
            app.Button_DataSpecifi = uibutton(app.Panel, 'push');
            app.Button_DataSpecifi.ButtonPushedFcn = createCallbackFcn(app, @Button_DataSpecifiPushed, true);
            app.Button_DataSpecifi.Text = '数据说明';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.Title = '3.步态识别方法选择';
            app.GyroButton = uiradiobutton(app.ButtonGroup);
            app.GyroButton.Text = 'Gyro';
            app.GyroButton.Value = true;
            app.AccelButton = uiradiobutton(app.ButtonGroup);
            app.AccelButton.Text = {'Accel'; ''};
            app.GyAcButton = uiradiobutton(app.ButtonGroup);
            app.GyAcButton.Text = 'Gy+Ac';
            app.Button_2 = uibutton(app.ButtonGroup, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '识别计算';
            app.EditField_StateCorrectLength = uieditfield(app.ButtonGroup, 'numeric');
            app.EditField_StateCorrectLength.ValueChangedFcn = createCallbackFcn(app, @EditField_StateCorrectLengthValueChanged, true);
            app.EditField_StateCorrectLength.Value = 10;
            app.Button_StateCorrect = uibutton(app.ButtonGroup, 'push');
            app.Button_StateCorrect.ButtonPushedFcn = createCallbackFcn(app, @Button_StateCorrectPushed, true);
            app.Button_StateCorrect.Text = '修正';
            app.Panel_2 = uipanel(app.UIFigure);
            app.Panel_2.Title = '4.绘制结果';
            app.GyroCheckBox = uicheckbox(app.Panel_2);
            app.GyroCheckBox.Enable = 'off';
            app.GyroCheckBox.Text = 'Gyro';
            app.AccelCheckBox = uicheckbox(app.Panel_2);
            app.AccelCheckBox.Enable = 'off';
            app.AccelCheckBox.Text = 'Accel';
            app.Gyro_And_AccelCheckBox = uicheckbox(app.Panel_2);
            app.Gyro_And_AccelCheckBox.Enable = 'off';
            app.Gyro_And_AccelCheckBox.Text = 'Gyro_And_Accel';
            app.Button_3 = uibutton(app.Panel_2, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '结果绘制';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ZeroDetection

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end