classdef ZeroDetection_V2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Label_Title                    matlab.ui.control.Label
        LoadDataPanel                  matlab.ui.container.Panel
        Button_LD_Open                 matlab.ui.control.Button
        EditField_LD_DataPath          matlab.ui.control.EditField
        Button_LD_Specifi              matlab.ui.control.Button
        SampleHzLabel                  matlab.ui.control.Label
        EditField_Data_ParaHz          matlab.ui.control.NumericEditField
        SetParameterPanel              matlab.ui.container.Panel
        BeginEditFieldLabel            matlab.ui.control.Label
        EditField_SP_TimeBegin         matlab.ui.control.NumericEditField
        EndEditFieldLabel              matlab.ui.control.Label
        EditField_SP_TimeEnd           matlab.ui.control.NumericEditField
        Label_SetTime                  matlab.ui.control.Label
        Label_Accelerometer            matlab.ui.control.Label
        AmpMeanEditFieldLabel          matlab.ui.control.Label
        EditField_SP_AccAmpMean        matlab.ui.control.NumericEditField
        AmpRmsEditFieldLabel           matlab.ui.control.Label
        EditField_SP_AccAmpRms         matlab.ui.control.NumericEditField
        ThresholdLabel                 matlab.ui.control.Label
        EditField_SP_AccMeanThreshold  matlab.ui.control.NumericEditField
        ThresholdsigmaLabel            matlab.ui.control.Label
        EditField_SP_AccRmsThreshold   matlab.ui.control.NumericEditField
        Label_Gyroscope                matlab.ui.control.Label
        AmpMeanEditFieldLabel_2        matlab.ui.control.Label
        EditField_SP_GyrAmpMean        matlab.ui.control.NumericEditField
        AmpRmsEditFieldLabel_2         matlab.ui.control.Label
        EditField_SP_GyrAmpRms         matlab.ui.control.NumericEditField
        ThresholdLabel_2               matlab.ui.control.Label
        EditField_SP_GyrMeanThreshold  matlab.ui.control.NumericEditField
        ThresholdsigmaLabel_2          matlab.ui.control.Label
        EditField_SP_GyrRmsThreshold   matlab.ui.control.NumericEditField
        DetectiTimeWindowHzLabel       matlab.ui.control.Label
        EditField_SP_DetectWindow      matlab.ui.control.NumericEditField
        Button_SetPara                 matlab.ui.control.Button
        DetectionChoicePanel           matlab.ui.container.Panel
        AccelerometerLabel             matlab.ui.control.Label
        CheckBox_DC_AccAmpMean         matlab.ui.control.CheckBox
        CheckBox_DC_AccAmpRms          matlab.ui.control.CheckBox
        CheckBox_DC_AccAmp             matlab.ui.control.CheckBox
        Gyroscope_XLabel_2             matlab.ui.control.Label
        CheckBox_DC_GyrAmpMean         matlab.ui.control.CheckBox
        CheckBox_DC_GyrAmpRms          matlab.ui.control.CheckBox
        CheckBox_DC_GyrAmp             matlab.ui.control.CheckBox
        Button_Detection               matlab.ui.control.Button
        Button_SaveResult              matlab.ui.control.Button
        DrawDataPanel                  matlab.ui.container.Panel
        AccelerometerLabel_2           matlab.ui.control.Label
        CheckBox_DD_AccAmpMean         matlab.ui.control.CheckBox
        CheckBox_DD_AccAmpRms          matlab.ui.control.CheckBox
        CheckBox_DD_AccAmp             matlab.ui.control.CheckBox
        Gyroscope_XLabel               matlab.ui.control.Label
        CheckBox_DD_GyrAmpMean         matlab.ui.control.CheckBox
        CheckBox_DD_GyrAmpRms          matlab.ui.control.CheckBox
        CheckBox_DD_GyrAmp             matlab.ui.control.CheckBox
        DrawDataButton                 matlab.ui.control.StateButton
    end

    
    properties (Access = public)
        % 文件路径
            FilePath_IMUData            string
            FileName_IMUData            string
        
        % 数据相关
            Data_ParaHz                 double   % 数据的采样频率
            Data_IMU                    double  % 读取的IMU数据
        
        % 检测参数设置  SetPara 简写为 SP
            % 时间设置
            SP_TimeBegin                double   % 参数提取的起始时间 单位s
            SP_TimeEnd                  double
        
            % 参数设置
            SP_AccAmpMean               double  % 加计检测的幅值均值
            SP_AccAmpRms                double  % 加计检测的幅值方差
            SP_AccMeanThreshold         double  % 加计幅值均值检测阈值 %
            SP_AccRmsThreshold          double  % 加计方差均值检测阈值 sigma
            SP_GyrAmpMean               double  % 加计检测的幅值均值
            SP_GyrAmpRms                double  % 加计检测的幅值方差
            SP_GyrMeanThreshold         double  % 陀螺幅值均值检测阈值 %
            SP_GyrRmsThreshold          double  % 陀螺方差均值检测阈值  sigma     
            SP_DetecTimeWindow          double   % 检测时间窗口长度 Hz            
        
        % 检测标志
            Check_IMUData               double   % 是否正确打开IMU数据
            Check_SetPara               double   % 检测参数是否设置完成
            Check_StateDone             double   % 零速检测是否完成
            
            Check_DC_AccAmp             double   % 用于 检测 绘制 的标志 加计幅值
            Check_DC_AccAmpMean         double
            Check_DC_AccAmpRms          double
            Check_DC_GyrAmp             double   % 用于 检测 绘制 的标志 陀螺幅值
            Check_DC_GyrAmpMean         double
            Check_DC_GyrAmpRms          double            

            Check_DD_AccAmp             double   % 用于 检测 绘制 的标志 加计幅值
            Check_DD_AccAmpMean         double
            Check_DD_AccAmpRms          double
            Check_DD_GyrAmp             double   % 用于 检测 绘制 的标志 陀螺幅值
            Check_DD_GyrAmpMean         double
            Check_DD_GyrAmpRms          double            
        % 检测数据 用于判断
            JudgeData_AccAmp            double  % 存储每一时刻加计的幅值
            JudgeData_AccMeanWindow     double  % 存储 时间窗参数 范围内的加计幅值均值 选用当前时刻之前的所有数据计算
            JudgeData_AccRmsWindow      double  % 存储 时间窗参数 范围内加计幅值的方差 选用当前时刻之前的所有数据计算
            JudgeData_GyrAmp            double  
            JudgeData_GyrMeanWindow     double
            JudgeData_GyrRmsWindow      double
            
        % 零速检测结果
            ZeroState                   double
    end
    
    methods (Access = public)
        
        function results = DrawData_Original(app)
            if app.Check_SetPara ~= 1
                msgbox('SetParameter is not done!');    
                results = 0;
                return;
            end
            
            L = length(app.Data_IMU);
            figure;
            legend on;
            % ------------------------------加计相关的绘制------------------     
            % 绘制加计幅值
            if app.Check_DD_AccAmp == 1                
                hold on;
                plot((1:L),app.JudgeData_AccAmp(:,2),'Color','k','DisplayName','AccAmp');   
            end
            % 绘制加计幅值 均值 及 判断范围
            if app.Check_DD_AccAmpMean == 1
                hold on;
                plot((1:L),app.JudgeData_AccMeanWindow(:,2),'LineWidth',1,'Color','c','DisplayName','AccAmpMean');  
                % 绘制均值的范围+ 虚线
                hold on;
                plot((1:L),ones(1,L).*((1+app.SP_AccMeanThreshold)*app.SP_AccAmpMean),'LineStyle','--','LineWidth',1,'Color','c','DisplayName','AccAmpMeanThreshold+');  
                hold on;
                plot((1:L),ones(1,L).*((1-app.SP_AccMeanThreshold)*app.SP_AccAmpMean),'LineStyle','--','LineWidth',1,'Color','c','DisplayName','AccAmpMeanThreshold-');  
            end            
            % 绘制加计幅值 方差 及 判断范围
            if app.Check_DD_AccAmpRms == 1
                hold on;
                plot((1:L),app.JudgeData_AccRmsWindow(:,2)+app.SP_AccAmpMean,'LineWidth',1,'Color','b','DisplayName','AccAmpRms');   
                % 绘制方差的范围+ 虚线
                hold on;
                plot((1:L),ones(1,L).*(app.SP_AccRmsThreshold*app.SP_AccAmpRms+app.SP_AccAmpMean),'LineStyle','--','LineWidth',1,'Color','b','DisplayName','AccAmpRmsThreshold+');               
            end    


            % ------------------------------陀螺相关的绘制------------------            
            % 绘制陀螺幅值
            if app.Check_DD_GyrAmp == 1                
                hold on;
                plot((1:L),app.JudgeData_GyrAmp(:,2),'LineWidth',1,'Color','g','DisplayName','GyrAmp');   
            end
            % 绘制陀螺幅值 均值 及 判断范围
            if app.Check_DD_GyrAmpMean == 1
                hold on;
                plot((1:L),app.JudgeData_GyrMeanWindow(:,2),'LineWidth',1,'Color','m','DisplayName','GyrAmpMean');  
                % 绘制均值的范围+ 虚线
                hold on;
                plot((1:L),ones(1,L).*((1+app.SP_GyrMeanThreshold)*app.SP_GyrAmpMean),'LineStyle','--','LineWidth',1,'Color','m','DisplayName','GyrAmpMeanThreshold+');  
            end            
            % 绘制陀螺幅值 方差 及 判断范围
            if app.Check_DD_GyrAmpRms == 1
                hold on;
                plot((1:L),app.JudgeData_GyrRmsWindow(:,2),'LineWidth',1,'Color','y','DisplayName','GyrAmpRms');   
                % 绘制方差的范围+ 虚线
                hold on;
                plot((1:L),ones(1,L).*(app.SP_GyrRmsThreshold*app.SP_GyrAmpRms),'LineStyle','--','LineWidth',1,'Color','y','DisplayName','GyrAmpRmsThreshold+');               
            end            
            
            % 绘制零速检测结果
            if app.Check_StateDone ==1
                hold on;
                plot((1:L),app.ZeroState(:,2),'Color','r','DisplayName','State');
            end
            hold off;
            grid on;            
            results = 1;            
%             
%             
%                             %  Z轴加速度计原始输出
%                 plot((1:L),app.IMUData(1:L,4)-1,'Color','k','DisplayName','zAccel');  
%                 %  Z轴加速度计 方差统计
%                 hold on; plot(app.RmsAccel(:,1),app.RmsAccel(:,2),'Color','k','LineWidth',2,'DisplayName','zAccel 方差'); 
%                 %  Z轴加速度计 方差阈值
%                 hold on; plot((1:L),ones(L,1).*(app.rmsAccel*app.rmsAccel_threshold),'Color','k','LineStyle','-.','DisplayName','zAccel 方差阈值');  
%                 
%                 %  加速度计幅值
%                 hold on; plot((1:L),app.MeanAccel,'Color','m','DisplayName','Accel 幅值');
%                 %  加速度计幅值 阈值
%                 hold on; plot((1:L),ones(L,1).*(app.meanAccel*(1+app.meanAccel_threshold)),'Color','m','LineStyle','-.','DisplayName','Accel 幅值阈值+'); 
%                 hold on; plot((1:L),ones(L,1).*(app.meanAccel*(1-app.meanAccel_threshold)),'Color','m','LineStyle','-.','DisplayName','Accel 幅值阈值-');
%                 
%                 %判断结果
%                 hold on; plot((1:L),app.FootState_Accel(:,2).*1.5,'Color','r','DisplayName','state');  
%                 title('加计幅值+方差  判断');legend;  
%             
%             
%             
%             
%             plot((1:L),app.IMUData(1:L,5),'Color','k','DisplayName','xGyro');  
%             % x轴陀螺 方差统计
%             hold on; plot(app.RmsGyro(:,1),app.RmsGyro(:,2),'Color','k','LineWidth',2,'DisplayName','xGyro 方差'); 
%             %  x轴陀螺 方差阈值
%             hold on; plot((1:L),ones(L,1).*(app.rmsXGyro*app.rmsXGyro_threshold),'Color','k','LineStyle','-.','DisplayName','xGyro 方差阈值');  
%                          
%             % x轴陀螺幅值
%             hold on; plot((1:L),app.meanGyro,'Color','m','DisplayName','xGyro 幅值');
%             % x轴陀螺幅值 阈值
%             hold on; plot((1:L),ones(L,1).*(app.meanXGyro*(1+app.meanXGyro_threshold)),'Color','m','LineStyle','-.','DisplayName','XGyro 幅值阈值+'); 
%             hold on; plot((1:L),ones(L,1).*(app.meanXGyro*(1-app.meanXGyro_threshold)),'Color','m','LineStyle','-.','DisplayName','XGyro 幅值阈值-');
%             
%             %判断结果
%             hold on; plot((1:L),app.FootState_Gyro(:,2).*1.5,'Color','r','DisplayName','State');  
%             title('x轴陀螺 方差+幅值  判断');legend;     
%             results = 1;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Check_IMUData = 0;            
            app.Check_SetPara = 0;
            app.Check_StateDone = 0;
            app.Check_DC_AccAmp = app.CheckBox_DC_AccAmp.Value;
            app.Check_DC_AccAmpMean = app.CheckBox_DC_AccAmpMean.Value;
            app.Check_DC_AccAmpRms = app.CheckBox_DC_AccAmpRms.Value;
            app.Check_DC_GyrAmp = app.CheckBox_DC_GyrAmp.Value;
            app.Check_DC_GyrAmpMean = app.CheckBox_DC_GyrAmpMean.Value;
            app.Check_DC_GyrAmpRms = app.CheckBox_DC_GyrAmpRms.Value;

            app.Check_DD_AccAmp = app.CheckBox_DD_AccAmp.Value;
            app.Check_DD_AccAmpMean = app.CheckBox_DD_AccAmpMean.Value;
            app.Check_DD_AccAmpRms = app.CheckBox_DD_AccAmpRms.Value;
            app.Check_DD_GyrAmp = app.CheckBox_DD_GyrAmp.Value;
            app.Check_DD_GyrAmpMean = app.CheckBox_DD_GyrAmpMean.Value;
            app.Check_DD_GyrAmpRms = app.CheckBox_DD_GyrAmpRms.Value;            
            
            app.SP_TimeBegin = app.EditField_SP_TimeBegin.Value;
            app.SP_TimeEnd = app.EditField_SP_TimeEnd.Value;
            app.SP_AccMeanThreshold = app.EditField_SP_AccMeanThreshold.Value / 100.0;
            app.SP_AccRmsThreshold = app.EditField_SP_AccRmsThreshold.Value;
            app.SP_GyrMeanThreshold = app.EditField_SP_GyrMeanThreshold.Value / 100.0;
            app.SP_GyrRmsThreshold = app.EditField_SP_GyrRmsThreshold.Value;
            app.SP_DetecTimeWindow = app.EditField_SP_DetectWindow.Value;
            app.Data_ParaHz = app.EditField_Data_ParaHz.Value;
        end

        % Button pushed function: Button_LD_Open
        function Button_LD_OpenPushed(app, event)
            [IMU_FileName,IMU_FilePath] = uigetfile('*.mat');
            if isequal(IMU_FileName,0)
               msgbox('Warning:Not Choose IMU Data!');
               app.Check_IMUData = 0;
            else               
               app.FilePath_IMUData = IMU_FilePath;
               app.FileName_IMUData = IMU_FileName;
               app.EditField_LD_DataPath.Value = fullfile(IMU_FilePath,IMU_FileName);
               load(fullfile(IMU_FilePath,IMU_FileName));
               if exist('IMU','var') ~= 1
                   msgbox('Warning：IMU is empty!');
                   app.Check_IMUData = 0;
               else
                   app.Data_IMU = IMU;
                   app.Check_IMUData = 1;
               end  
            end        
        end

        % Button pushed function: Button_LD_Specifi
        function Button_LD_SpecifiPushed(app, event)
            msgbox('InputDataSpecify:(1)Data Var Name "IMU";(2)Data Colum:1 time，234 Acc xyz，567 Gyr xyz。');
        end

        % Button pushed function: Button_SetPara
        function Button_SetParaPushed(app, event)
            
            if app.Check_IMUData == 0
                msgbox('Warning:Not Choose IMU Data!');
                return;
            end                        
            %计算 所需的时间长度
            L_IMU = length(app.Data_IMU);
            L = (app.SP_TimeEnd - app.SP_TimeBegin) * app.Data_ParaHz;
            if (L <= 0.0) || (L > L_IMU)
                msgbox('The SetParamenter TimeBegin and End is wrong!');
                return;
            end
            tpNumStart = app.SP_TimeBegin*app.Data_ParaHz;
            if tpNumStart == 0
                tpNumStart = 1;
            end
            tpNumEnd = app.SP_TimeEnd*app.Data_ParaHz;
            
            L = tpNumEnd - tpNumStart + 1;

            
        %--------------------------参数提取--------------------------------
            % 试验 利用陀螺和加计的3轴矢量 幅值和方差 
            % 提取 陀螺 和 加计 原始数据
            Acc = app.Data_IMU(tpNumStart:tpNumEnd,2:4);
            Gyr = app.Data_IMU(tpNumStart:tpNumEnd,5:7);
            
            % 加计矢量幅值统计
            tmpAmp_AccMean = zeros(1,L);
            tmpAmp_GyrMean = zeros(1,L);
            for i=1:L
                tmpAmp_AccMean(1,i) = norm(Acc(i,:));
                tmpAmp_GyrMean(1,i) = norm(Gyr(i,1));   % x轴陀螺
            end
            app.SP_AccAmpMean = mean(tmpAmp_AccMean);
            app.SP_GyrAmpMean = mean(tmpAmp_GyrMean);
            app.EditField_SP_AccAmpMean.Value = app.SP_AccAmpMean;
            app.EditField_SP_GyrAmpMean.Value = app.SP_GyrAmpMean;
            
            % 加计矢量方差统计 取全部数据的方差
            app.SP_AccAmpRms = sqrt(var(tmpAmp_AccMean));
            app.EditField_SP_AccAmpRms.Value = app.SP_AccAmpRms;
            app.SP_GyrAmpRms = sqrt(var(tmpAmp_GyrMean));
            app.EditField_SP_GyrAmpRms.Value = app.SP_GyrAmpRms;
        %-----------------------------------------------------------------
        
        %-----------------------计算所有需要的数据--------------------------
            L = length(app.Data_IMU);
            app.JudgeData_AccAmp = zeros(L,2);
            app.JudgeData_GyrAmp = zeros(L,2);
            app.JudgeData_AccMeanWindow = zeros(L,2);
            app.JudgeData_AccRmsWindow = zeros(L,2);
            app.JudgeData_GyrMeanWindow = zeros(L,2);
            app.JudgeData_GyrRmsWindow = zeros(L,2);
            
            % time
            app.JudgeData_AccAmp(:,1) = app.Data_IMU(:,1);
            app.JudgeData_GyrAmp(:,1) = app.Data_IMU(:,1);
            app.JudgeData_AccMeanWindow(:,1) = app.Data_IMU(:,1);
            app.JudgeData_AccRmsWindow(:,1) = app.Data_IMU(:,1);
            app.JudgeData_GyrMeanWindow(:,1) = app.Data_IMU(:,1);
            app.JudgeData_GyrRmsWindow(:,1) = app.Data_IMU(:,1);
            
            tmpWindow = app.SP_DetecTimeWindow;
            
            for i=1:L
                app.JudgeData_AccAmp(i,2) = norm(app.Data_IMU(i,2:4));                
                app.JudgeData_GyrAmp(i,2) = norm(app.Data_IMU(i,5:7));                
                if i >= tmpWindow
                    if i == tmpWindow
                        tmpMean = mean(app.JudgeData_AccAmp(1:i,2));
                        app.JudgeData_AccMeanWindow(1:i,2) = tmpMean;
                        tmpMean = mean(app.JudgeData_GyrAmp(1:i,2));
                        app.JudgeData_GyrMeanWindow(1:i,2) = tmpMean; 
                        tmpRms = sqrt(var(app.JudgeData_AccAmp(1:i,2)));
                        app.JudgeData_AccRmsWindow(1:i,2) = tmpRms;          
                        tmpRms = sqrt(var(app.JudgeData_GyrAmp(1:i,2)));
                        app.JudgeData_GyrRmsWindow(1:i,2) = tmpRms;                           
                    else
                        tmp = i-tmpWindow+1;
                        app.JudgeData_AccMeanWindow(i,2) = mean(app.JudgeData_AccAmp(tmp:i,2));
                        app.JudgeData_GyrMeanWindow(i,2) = mean(app.JudgeData_GyrAmp(tmp:i,2));
                        app.JudgeData_AccRmsWindow(i,2) = sqrt(var(app.JudgeData_AccAmp(tmp:i,2)));
                        app.JudgeData_GyrRmsWindow(i,2) = sqrt(var(app.JudgeData_GyrAmp(tmp:i,2)));
                    end                    
                end
            end      
            
            app.Check_SetPara = 1;
            
            msgbox('SetParameter is done!');            
        end

        % Value changed function: EditField_SP_TimeBegin
        function EditField_SP_TimeBeginValueChanged(app, event)
            value = app.EditField_SP_TimeBegin.Value;
            app.SP_TimeBegin = value;
        end

        % Value changed function: EditField_SP_TimeEnd
        function EditField_SP_TimeEndValueChanged(app, event)
            value = app.EditField_SP_TimeEnd.Value;
            app.SP_TimeEnd = value;
        end

        % Value changed function: EditField_SP_AccMeanThreshold
        function EditField_SP_AccMeanThresholdValueChanged(app, event)
            value = app.EditField_SP_AccMeanThreshold.Value;
            app.SP_AccMeanThreshold = value / 100.0;
        end

        % Value changed function: EditField_SP_AccRmsThreshold
        function EditField_SP_AccRmsThresholdValueChanged(app, event)
            value = app.EditField_SP_AccRmsThreshold.Value;
            app.SP_AccRmsThreshold = value;
        end

        % Value changed function: EditField_SP_GyrMeanThreshold
        function EditField_SP_GyrMeanThresholdValueChanged(app, event)
            value = app.EditField_SP_GyrMeanThreshold.Value;
            app.SP_GyrMeanThreshold = value / 100.0;            
        end

        % Value changed function: EditField_SP_GyrRmsThreshold
        function EditField_SP_GyrRmsThresholdValueChanged(app, event)
            value = app.EditField_SP_GyrRmsThreshold.Value;
            app.SP_GyrRmsThreshold = value / 100.0;
        end

        % Value changed function: EditField_SP_DetectWindow
        function EditField_SP_DetectWindowValueChanged(app, event)
            value = app.EditField_SP_DetectWindow.Value;
            app.SP_DetecTimeWindow = value;
%             msgbox('Need Repress "SetPara" Button!');
        end

        % Value changed function: CheckBox_DC_AccAmp
        function CheckBox_DC_AccAmpValueChanged(app, event)
            value = app.CheckBox_DC_AccAmp.Value;
            app.Check_DC_AccAmp = value;
        end

        % Value changed function: CheckBox_DC_AccAmpMean
        function CheckBox_DC_AccAmpMeanValueChanged(app, event)
            value = app.CheckBox_DC_AccAmpMean.Value;
            app.Check_DC_AccAmpMean = value;
        end

        % Value changed function: CheckBox_DC_AccAmpRms
        function CheckBox_DC_AccAmpRmsValueChanged(app, event)
            value = app.CheckBox_DC_AccAmpRms.Value;
            app.Check_DC_AccAmpRms = value;
        end

        % Value changed function: CheckBox_DC_GyrAmp
        function CheckBox_DC_GyrAmpValueChanged(app, event)
            value = app.CheckBox_DC_GyrAmp.Value;
            app.Check_DC_GyrAmp = value;
        end

        % Value changed function: CheckBox_DC_GyrAmpMean
        function CheckBox_DC_GyrAmpMeanValueChanged(app, event)
            value = app.CheckBox_DC_GyrAmpMean.Value;
            app.Check_DC_GyrAmpMean = value;
        end

        % Value changed function: CheckBox_DC_GyrAmpRms
        function CheckBox_DC_GyrAmpRmsValueChanged(app, event)
            value = app.CheckBox_DC_GyrAmpRms.Value;
            app.Check_DC_GyrAmpRms = value;
        end

        % Value changed function: DrawDataButton
        function DrawDataButtonValueChanged(app, event)
            value = app.DrawDataButton.Value;
            DrawData_Original(app);
        end

        % Value changed function: CheckBox_DD_AccAmp
        function CheckBox_DD_AccAmpValueChanged(app, event)
            app.Check_DD_AccAmp = app.CheckBox_DD_AccAmp.Value;   
        end

        % Value changed function: CheckBox_DD_AccAmpMean
        function CheckBox_DD_AccAmpMeanValueChanged(app, event)
            app.Check_DD_AccAmpMean = app.CheckBox_DD_AccAmpMean.Value;
            
        end

        % Value changed function: CheckBox_DD_AccAmpRms
        function CheckBox_DD_AccAmpRmsValueChanged(app, event)
            app.Check_DD_AccAmpRms = app.CheckBox_DD_AccAmpRms.Value;
            
        end

        % Value changed function: CheckBox_DD_GyrAmp
        function CheckBox_DD_GyrAmpValueChanged(app, event)
            app.Check_DD_GyrAmp = app.CheckBox_DD_GyrAmp.Value;
            
        end

        % Value changed function: CheckBox_DD_GyrAmpMean
        function CheckBox_DD_GyrAmpMeanValueChanged(app, event)
            app.Check_DD_GyrAmpMean = app.CheckBox_DD_GyrAmpMean.Value;
            
        end

        % Value changed function: CheckBox_DD_GyrAmpRms
        function CheckBox_DD_GyrAmpRmsValueChanged(app, event)
            app.Check_DD_GyrAmpRms = app.CheckBox_DD_GyrAmpRms.Value;
            
        end

        % Button pushed function: Button_Detection
        function Button_DetectionPushed(app, event)
            % 零速检测
            L = length(app.Data_IMU);
            app.ZeroState = zeros(L,2);
            app.ZeroState(:,1) = app.Data_IMU(:,1);
            AccAmpMax = app.SP_AccAmpMean*(1+app.SP_AccMeanThreshold);
            AccAmpMin = app.SP_AccAmpMean*(1-app.SP_AccMeanThreshold);
            GyrAmpMax = app.SP_GyrAmpMean*(1+app.SP_GyrMeanThreshold);

            
            % 第一个时刻认为是静止状态
            app.ZeroState(1,2) = 1;
            for i=2:L
                tmpAccAmp = app.JudgeData_AccMeanWindow(i,2);
                tmpGyrAmp = app.JudgeData_GyrMeanWindow(i,2);
                if (tmpAccAmp<AccAmpMax) && (tmpAccAmp>=AccAmpMin)
                    if app.ZeroState(i-1,2) == 1
                        app.ZeroState(i,2) = 1;
                    else
                        if (tmpGyrAmp > GyrAmpMax)
                            app.ZeroState(i,2) = 0;
                        else
                            app.ZeroState(i,2) = 1;
                        end
                    end
                else
                    if app.ZeroState(i-1,2) == 1
                        if (tmpGyrAmp <= GyrAmpMax)
                            app.ZeroState(i,2) = 1;
                        else
                            app.ZeroState(i,2) = 0;
                        end                         
                    else
                        app.ZeroState(i,2) = 0;
                    end
                end

            end
            app.Check_StateDone = 1;
        end

        % Button pushed function: Button_SaveResult
        function Button_SaveResultPushed(app, event)
            if app.Check_StateDone == 1
                State_AG = app.ZeroState;
                save(fullfile(app.FilePath_IMUData,app.FileName_IMUData),'State_AG','-append');
            else
                msgbox('Detection is not Done!');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Label_Title = uilabel(app.UIFigure);
            app.Label_Title.Text = {'Zero-VelocityDetection_v2.0'; ''};
            app.LoadDataPanel = uipanel(app.UIFigure);
            app.LoadDataPanel.Title = '1.LoadData';
            app.Button_LD_Open = uibutton(app.LoadDataPanel, 'push');
            app.Button_LD_Open.ButtonPushedFcn = createCallbackFcn(app, @Button_LD_OpenPushed, true);
            app.Button_LD_Open.Text = 'Open';
            app.EditField_LD_DataPath = uieditfield(app.LoadDataPanel, 'text');
            app.Button_LD_Specifi = uibutton(app.LoadDataPanel, 'push');
            app.Button_LD_Specifi.ButtonPushedFcn = createCallbackFcn(app, @Button_LD_SpecifiPushed, true);
            app.Button_LD_Specifi.Text = 'Specification';
            app.SampleHzLabel = uilabel(app.LoadDataPanel);
            app.SampleHzLabel.Text = 'Sample(Hz)';
            app.EditField_Data_ParaHz = uieditfield(app.LoadDataPanel, 'numeric');
            app.EditField_Data_ParaHz.Value = 200;
            app.SetParameterPanel = uipanel(app.UIFigure);
            app.SetParameterPanel.Title = '2.SetParameter';
            app.BeginEditFieldLabel = uilabel(app.SetParameterPanel);
            app.BeginEditFieldLabel.Text = 'Begin';
            app.EditField_SP_TimeBegin = uieditfield(app.SetParameterPanel, 'numeric');
            app.EditField_SP_TimeBegin.ValueChangedFcn = createCallbackFcn(app, @EditField_SP_TimeBeginValueChanged, true);
            app.EndEditFieldLabel = uilabel(app.SetParameterPanel);
            app.EndEditFieldLabel.Text = 'End';
            app.EditField_SP_TimeEnd = uieditfield(app.SetParameterPanel, 'numeric');
            app.EditField_SP_TimeEnd.ValueChangedFcn = createCallbackFcn(app, @EditField_SP_TimeEndValueChanged, true);
            app.EditField_SP_TimeEnd.Value = 5;
            app.Label_SetTime = uilabel(app.SetParameterPanel);
            app.Label_SetTime.Text = 'SetTime(s)';
            app.Label_Accelerometer = uilabel(app.SetParameterPanel);
            app.Label_Accelerometer.Text = 'Accelerometer';
            app.AmpMeanEditFieldLabel = uilabel(app.SetParameterPanel);
            app.AmpMeanEditFieldLabel.Text = 'AmpMean';
            app.EditField_SP_AccAmpMean = uieditfield(app.SetParameterPanel, 'numeric');
            app.EditField_SP_AccAmpMean.Editable = 'off';
            app.AmpRmsEditFieldLabel = uilabel(app.SetParameterPanel);
            app.AmpRmsEditFieldLabel.Text = 'AmpRms';
            app.EditField_SP_AccAmpRms = uieditfield(app.SetParameterPanel, 'numeric');
            app.EditField_SP_AccAmpRms.Editable = 'off';
            app.ThresholdLabel = uilabel(app.SetParameterPanel);
            app.ThresholdLabel.Text = 'Threshold(%)';
            app.EditField_SP_AccMeanThreshold = uieditfield(app.SetParameterPanel, 'numeric');
            app.EditField_SP_AccMeanThreshold.ValueChangedFcn = createCallbackFcn(app, @EditField_SP_AccMeanThresholdValueChanged, true);
            app.EditField_SP_AccMeanThreshold.Value = 3;
            app.ThresholdsigmaLabel = uilabel(app.SetParameterPanel);
            app.ThresholdsigmaLabel.Text = {'Threshold'; '(sigma)'};
            app.EditField_SP_AccRmsThreshold = uieditfield(app.SetParameterPanel, 'numeric');
            app.EditField_SP_AccRmsThreshold.ValueChangedFcn = createCallbackFcn(app, @EditField_SP_AccRmsThresholdValueChanged, true);
            app.EditField_SP_AccRmsThreshold.Value = 3;
            app.Label_Gyroscope = uilabel(app.SetParameterPanel);
            app.Label_Gyroscope.Text = 'Gyroscope';
            app.AmpMeanEditFieldLabel_2 = uilabel(app.SetParameterPanel);
            app.AmpMeanEditFieldLabel_2.Text = 'AmpMean';
            app.EditField_SP_GyrAmpMean = uieditfield(app.SetParameterPanel, 'numeric');
            app.EditField_SP_GyrAmpMean.Editable = 'off';
            app.AmpRmsEditFieldLabel_2 = uilabel(app.SetParameterPanel);
            app.AmpRmsEditFieldLabel_2.Text = 'AmpRms';
            app.EditField_SP_GyrAmpRms = uieditfield(app.SetParameterPanel, 'numeric');
            app.EditField_SP_GyrAmpRms.Editable = 'off';
            app.ThresholdLabel_2 = uilabel(app.SetParameterPanel);
            app.ThresholdLabel_2.Text = 'Threshold(%)';
            app.EditField_SP_GyrMeanThreshold = uieditfield(app.SetParameterPanel, 'numeric');
            app.EditField_SP_GyrMeanThreshold.ValueChangedFcn = createCallbackFcn(app, @EditField_SP_GyrMeanThresholdValueChanged, true);
            app.EditField_SP_GyrMeanThreshold.Value = 3;
            app.ThresholdsigmaLabel_2 = uilabel(app.SetParameterPanel);
            app.ThresholdsigmaLabel_2.Text = {'Threshold'; '(sigma)'};
            app.EditField_SP_GyrRmsThreshold = uieditfield(app.SetParameterPanel, 'numeric');
            app.EditField_SP_GyrRmsThreshold.ValueChangedFcn = createCallbackFcn(app, @EditField_SP_GyrRmsThresholdValueChanged, true);
            app.EditField_SP_GyrRmsThreshold.Value = 3;
            app.DetectiTimeWindowHzLabel = uilabel(app.SetParameterPanel);
            app.DetectiTimeWindowHzLabel.Text = 'DetectiTimeWindow(Hz)';
            app.EditField_SP_DetectWindow = uieditfield(app.SetParameterPanel, 'numeric');
            app.EditField_SP_DetectWindow.ValueChangedFcn = createCallbackFcn(app, @EditField_SP_DetectWindowValueChanged, true);
            app.EditField_SP_DetectWindow.Value = 8;
            app.Button_SetPara = uibutton(app.SetParameterPanel, 'push');
            app.Button_SetPara.ButtonPushedFcn = createCallbackFcn(app, @Button_SetParaPushed, true);
            app.Button_SetPara.Text = 'SetPara';
            app.DetectionChoicePanel = uipanel(app.UIFigure);
            app.DetectionChoicePanel.Title = '4.DetectionChoice';
            app.AccelerometerLabel = uilabel(app.DetectionChoicePanel);
            app.AccelerometerLabel.Text = 'Accelerometer';
            app.CheckBox_DC_AccAmpMean = uicheckbox(app.DetectionChoicePanel);
            app.CheckBox_DC_AccAmpMean.ValueChangedFcn = createCallbackFcn(app, @CheckBox_DC_AccAmpMeanValueChanged, true);
            app.CheckBox_DC_AccAmpMean.Text = 'Amplitude Mean';
            app.CheckBox_DC_AccAmpRms = uicheckbox(app.DetectionChoicePanel);
            app.CheckBox_DC_AccAmpRms.ValueChangedFcn = createCallbackFcn(app, @CheckBox_DC_AccAmpRmsValueChanged, true);
            app.CheckBox_DC_AccAmpRms.Text = 'Amplitude Rms';
            app.CheckBox_DC_AccAmp = uicheckbox(app.DetectionChoicePanel);
            app.CheckBox_DC_AccAmp.ValueChangedFcn = createCallbackFcn(app, @CheckBox_DC_AccAmpValueChanged, true);
            app.CheckBox_DC_AccAmp.Text = 'Amplitude';
            app.Gyroscope_XLabel_2 = uilabel(app.DetectionChoicePanel);
            app.Gyroscope_XLabel_2.Text = 'Gyroscope_X';
            app.CheckBox_DC_GyrAmpMean = uicheckbox(app.DetectionChoicePanel);
            app.CheckBox_DC_GyrAmpMean.ValueChangedFcn = createCallbackFcn(app, @CheckBox_DC_GyrAmpMeanValueChanged, true);
            app.CheckBox_DC_GyrAmpMean.Text = 'Amplitude Mean';
            app.CheckBox_DC_GyrAmpRms = uicheckbox(app.DetectionChoicePanel);
            app.CheckBox_DC_GyrAmpRms.ValueChangedFcn = createCallbackFcn(app, @CheckBox_DC_GyrAmpRmsValueChanged, true);
            app.CheckBox_DC_GyrAmpRms.Text = 'Amplitude Rms';
            app.CheckBox_DC_GyrAmp = uicheckbox(app.DetectionChoicePanel);
            app.CheckBox_DC_GyrAmp.ValueChangedFcn = createCallbackFcn(app, @CheckBox_DC_GyrAmpValueChanged, true);
            app.CheckBox_DC_GyrAmp.Text = 'Amplitude';
            app.Button_Detection = uibutton(app.DetectionChoicePanel, 'push');
            app.Button_Detection.ButtonPushedFcn = createCallbackFcn(app, @Button_DetectionPushed, true);
            app.Button_Detection.Text = 'Detection';
            app.Button_SaveResult = uibutton(app.DetectionChoicePanel, 'push');
            app.Button_SaveResult.ButtonPushedFcn = createCallbackFcn(app, @Button_SaveResultPushed, true);
            app.Button_SaveResult.Text = 'SaveResult';
            app.DrawDataPanel = uipanel(app.UIFigure);
            app.DrawDataPanel.Title = '3.DrawData';
            app.AccelerometerLabel_2 = uilabel(app.DrawDataPanel);
            app.AccelerometerLabel_2.Text = 'Accelerometer';
            app.CheckBox_DD_AccAmpMean = uicheckbox(app.DrawDataPanel);
            app.CheckBox_DD_AccAmpMean.ValueChangedFcn = createCallbackFcn(app, @CheckBox_DD_AccAmpMeanValueChanged, true);
            app.CheckBox_DD_AccAmpMean.Text = 'Amplitude Mean';
            app.CheckBox_DD_AccAmpRms = uicheckbox(app.DrawDataPanel);
            app.CheckBox_DD_AccAmpRms.ValueChangedFcn = createCallbackFcn(app, @CheckBox_DD_AccAmpRmsValueChanged, true);
            app.CheckBox_DD_AccAmpRms.Text = 'Amplitude Rms';
            app.CheckBox_DD_AccAmp = uicheckbox(app.DrawDataPanel);
            app.CheckBox_DD_AccAmp.ValueChangedFcn = createCallbackFcn(app, @CheckBox_DD_AccAmpValueChanged, true);
            app.CheckBox_DD_AccAmp.Text = 'Amplitude';
            app.Gyroscope_XLabel = uilabel(app.DrawDataPanel);
            app.Gyroscope_XLabel.Text = 'Gyroscope_X';
            app.CheckBox_DD_GyrAmpMean = uicheckbox(app.DrawDataPanel);
            app.CheckBox_DD_GyrAmpMean.ValueChangedFcn = createCallbackFcn(app, @CheckBox_DD_GyrAmpMeanValueChanged, true);
            app.CheckBox_DD_GyrAmpMean.Text = 'Amplitude Mean';
            app.CheckBox_DD_GyrAmpRms = uicheckbox(app.DrawDataPanel);
            app.CheckBox_DD_GyrAmpRms.ValueChangedFcn = createCallbackFcn(app, @CheckBox_DD_GyrAmpRmsValueChanged, true);
            app.CheckBox_DD_GyrAmpRms.Text = 'Amplitude Rms';
            app.CheckBox_DD_GyrAmp = uicheckbox(app.DrawDataPanel);
            app.CheckBox_DD_GyrAmp.ValueChangedFcn = createCallbackFcn(app, @CheckBox_DD_GyrAmpValueChanged, true);
            app.CheckBox_DD_GyrAmp.Text = 'Amplitude';
            app.DrawDataButton = uibutton(app.DrawDataPanel, 'state');
            app.DrawDataButton.ValueChangedFcn = createCallbackFcn(app, @DrawDataButtonValueChanged, true);
            app.DrawDataButton.Text = 'Draw Data';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ZeroDetection_V2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end